classdef appControlModes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIControlModesUIFigure          matlab.ui.Figure
        ControlModesPanel               matlab.ui.container.Panel
        RollandPitchstabilizemodeCheckBox  matlab.ui.control.CheckBox
        YawstabilizemodeCheckBox        matlab.ui.control.CheckBox
        XandYpositionmodeCheckBox       matlab.ui.control.CheckBox
        ZpositionmodeCheckBox           matlab.ui.control.CheckBox
        EnablepositionreferencesfromPCCheckBox  matlab.ui.control.CheckBox
        PositiondesiredPanel            matlab.ui.container.Panel
        TakeOffButton                   matlab.ui.control.Button
        XmEditFieldLabel                matlab.ui.control.Label
        XmEditField                     matlab.ui.control.NumericEditField
        YmEditFieldLabel                matlab.ui.control.Label
        YmEditField                     matlab.ui.control.NumericEditField
        ZmEditFieldLabel                matlab.ui.control.Label
        ZmEditField                     matlab.ui.control.NumericEditField
        YawdegEditFieldLabel            matlab.ui.control.Label
        YawdegEditField                 matlab.ui.control.NumericEditField
        SendButton                      matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        EnableGeometricControlCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        nameModelSimulink
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~,app.nameModelSimulink,~,~,~] = generatePathandNames();
            app.RollandPitchstabilizemodeCheckBox.Value = logical(str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/rollPitch_pos_rate'],'Value')));
            app.YawstabilizemodeCheckBox.Value = logical(str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/yaw_pos_rate'],'Value')));
            app.XandYpositionmodeCheckBox.Value = logical(str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/XY_pos_vel'],'Value')));
            app.ZpositionmodeCheckBox.Value = logical(str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/Z_pos_vel'],'Value')));
            app.EnablepositionreferencesfromPCCheckBox.Value = logical(str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/enable_PC'],'Value'))); 
            app.XmEditField.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/PositionDesired/Xdes'],'Value'));
            app.YmEditField.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/PositionDesired/Ydes'],'Value'));
            app.ZmEditField.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/PositionDesired/Zdes'],'Value'));
            app.YawdegEditField.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/PositionDesired/YAWdes'],'Value'));
            app.EnableGeometricControlCheckBox.Value = logical(str2double(get_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/geometricControl'],'Value')));
            if app.EnablepositionreferencesfromPCCheckBox.Value == 0
                app.PositiondesiredPanel.Visible = 'off';
            else
                app.PositiondesiredPanel.Visible = 'on';
            end
                
                
        end

        % Close request function: UIControlModesUIFigure
        function UIControlModesUIFigureCloseRequest(app, event)
            delete(app);
            
        end

        % Button pushed function: UpdateButton
        function update_fcn(app, event)
            set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/geometricControl'],'Value',num2str(app.EnableGeometricControlCheckBox.Value));
            set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/rollPitch_pos_rate'],'Value',num2str(app.RollandPitchstabilizemodeCheckBox.Value));
            set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/yaw_pos_rate'],'Value',num2str(app.YawstabilizemodeCheckBox.Value));
            set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/XY_pos_vel'],'Value',num2str(app.XandYpositionmodeCheckBox.Value));
            set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/Z_pos_vel'],'Value',num2str(app.ZpositionmodeCheckBox.Value));
            set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/enable_PC'],'Value',num2str(app.EnablepositionreferencesfromPCCheckBox.Value));
            if app.EnablepositionreferencesfromPCCheckBox.Value == 0
                app.PositiondesiredPanel.Visible = 'off';
            else
                app.PositiondesiredPanel.Visible = 'on';
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIControlModesUIFigure = uifigure;
            app.UIControlModesUIFigure.Name = 'UIControlModes';
            app.UIControlModesUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIControlModesUIFigureCloseRequest, true);
            app.ControlModesPanel = uipanel(app.UIControlModesUIFigure);
            app.ControlModesPanel.Title = 'Control Modes:';
            app.RollandPitchstabilizemodeCheckBox = uicheckbox(app.ControlModesPanel);
            app.RollandPitchstabilizemodeCheckBox.Text = 'Roll and Pitch stabilize mode';
            app.YawstabilizemodeCheckBox = uicheckbox(app.ControlModesPanel);
            app.YawstabilizemodeCheckBox.Text = 'Yaw stabilize mode';
            app.XandYpositionmodeCheckBox = uicheckbox(app.ControlModesPanel);
            app.XandYpositionmodeCheckBox.Text = 'X and Y position mode';
            app.ZpositionmodeCheckBox = uicheckbox(app.ControlModesPanel);
            app.ZpositionmodeCheckBox.Text = 'Z position mode';
            app.EnablepositionreferencesfromPCCheckBox = uicheckbox(app.ControlModesPanel);
            app.EnablepositionreferencesfromPCCheckBox.Text = 'Enable position references from PC';
            app.PositiondesiredPanel = uipanel(app.ControlModesPanel);
            app.PositiondesiredPanel.Title = 'Position desired:';
            app.TakeOffButton = uibutton(app.PositiondesiredPanel, 'push');
            app.TakeOffButton.Text = 'Take Off';
            app.XmEditFieldLabel = uilabel(app.PositiondesiredPanel);
            app.XmEditFieldLabel.Text = 'X [m]:';
            app.XmEditField = uieditfield(app.PositiondesiredPanel, 'numeric');
            app.YmEditFieldLabel = uilabel(app.PositiondesiredPanel);
            app.YmEditFieldLabel.Text = 'Y [m]:';
            app.YmEditField = uieditfield(app.PositiondesiredPanel, 'numeric');
            app.ZmEditFieldLabel = uilabel(app.PositiondesiredPanel);
            app.ZmEditFieldLabel.Text = 'Z [m]:';
            app.ZmEditField = uieditfield(app.PositiondesiredPanel, 'numeric');
            app.YawdegEditFieldLabel = uilabel(app.PositiondesiredPanel);
            app.YawdegEditFieldLabel.Text = 'Yaw [deg]:';
            app.YawdegEditField = uieditfield(app.PositiondesiredPanel, 'numeric');
            app.SendButton = uibutton(app.PositiondesiredPanel, 'push');
            app.SendButton.Text = 'Send';
            app.UpdateButton = uibutton(app.ControlModesPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @update_fcn, true);
            app.UpdateButton.Text = 'Update';
            app.EnableGeometricControlCheckBox = uicheckbox(app.ControlModesPanel);
            app.EnableGeometricControlCheckBox.Text = 'Enable Geometric Control';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appControlModes

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIControlModesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIControlModesUIFigure)
        end
    end
end